classdef LesionEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        LoadFLAIRButton              matlab.ui.control.Button
        LoadsegmentationButton       matlab.ui.control.Button
        StartdeletingButton          matlab.ui.control.Button
        SaveeditedsegmButton         matlab.ui.control.Button
        CoronalButton                matlab.ui.control.Button
        SagittalButton               matlab.ui.control.Button
        AxialButton                  matlab.ui.control.Button
        SliceSliderLabel             matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.Slider
        MinvalSliderLabel            matlab.ui.control.Label
        MinvalSlider                 matlab.ui.control.Slider
        MaxvalSliderLabel            matlab.ui.control.Label
        MaxvalSlider                 matlab.ui.control.Slider
        UndoButton                   matlab.ui.control.Button
        ViewcontrolLabel             matlab.ui.control.Label
        DatamgmtLabel                matlab.ui.control.Label
        Labelediting                 matlab.ui.control.Label
        SelectionsensitivityButton   matlab.ui.control.Button
        BrightnessandslicemgmtLabel  matlab.ui.control.Label
        StopdeletingButton           matlab.ui.control.Button
        LesionEditorBetaLabel        matlab.ui.control.Label
        WorkingonnothingloadedLabel  matlab.ui.control.Label
        DrawButton                   matlab.ui.control.Button
        DeleteallButton              matlab.ui.control.Button
        MagicdrawButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        data, MinValue, MaxValue, current_slice % Description
        view_axis, segmentation, labels, max_label
        cMinValue, cMaxValue
        bkseg % for undo purposes
        selector_size % size of deletion window
        mouse_pos % test for avoiding ginput
        should_show_selection, selection_contour % selection square
        drawing % to draw a new ROI
    end
    
    methods (Access = private)
        
        function UpdateImage(app)
            app.UpdateImageForAxis(app.UIAxes);
        end
        
        function UpdateImageForAxis(app,the_axis)
            if(isprop(app,'data'))
                if(app.view_axis == 3)
                    SL = app.data.img(:,:,app.current_slice);
                elseif(app.view_axis == 2)
                    SL = squeeze(app.data.img(:,app.current_slice,:));
                elseif(app.view_axis == 1)
                    SL = squeeze(app.data.img(app.current_slice,:,:));
                end
                %                 imshow(SL,[app.cMinValue app.cMaxValue],'Parent',the_axis);
                h = imagesc(the_axis,SL,[app.cMinValue app.cMaxValue]);
                set(h,'ButtonDownFcn',@app.MouseClickedInImage);
                
            end
            if(isprop(app,'labels'))
                if(~isempty(app.labels))
                    %                     for ij=1:app.max_label
                    L = app.labels > 0;% == ij;
                    if(app.view_axis == 3)
                        SL = L(:,:,app.current_slice);
                    elseif(app.view_axis == 2)
                        SL = squeeze(L(:,app.current_slice,:));
                    elseif(app.view_axis == 1)
                        SL = squeeze(L(app.current_slice,:,:));
                    end
                    if(~isempty(find(SL,1)))
                        hold(the_axis,'on');
                        if(app.should_show_selection == true)
                            contour(the_axis,SL,'r','HitTest','on','ButtonDownFcn',@app.MouseClickedInImage);
                        else
                            contour(the_axis,SL,'y','HitTest','on','ButtonDownFcn',@app.MouseClickedInImage);
                        end
                        hold(the_axis,'off');
                    end
                    %                     end
                end
                if(isfield(app.drawing,'points') && ~isempty(app.drawing.points))
                    L = app.drawing.points(:,3) == app.current_slice;
                    if(~isempty(L))
                        if(~isfield(app.drawing,'handles'))
                            app.drawing.handles = [];
                        else
                            app.DeleteAllDrawingPoints();
                        end
                        hold(the_axis,'on');
                        h = plot(the_axis,app.drawing.points(L,1),app.drawing.points(L,2),'.-g');
                        hold(the_axis,'off');
                        app.drawing.handles = [app.drawing.handles;h];
                    end
                end
            end
        end
        
        function UpdateSliceSlider(app)
            app.SliceSlider.MajorTicks = 1:10:size(app.data.img,app.view_axis);
            app.SliceSlider.Value = double(round(app.current_slice/size(app.data.img,app.view_axis)*100));
        end
        
        function DeleteAllDrawingPoints(app)
            if(isfield(app.drawing,'handles'))
                for ij=1:length(app.drawing.handles)
                    delete(app.drawing.handles(ij));
                end
            end
            app.drawing.handles = [];
        end
        
        function ValidateDrawingPoints(app)
            if(~isprop(app,'labels') || isempty(app.labels) || ...
                    ~isfield(app.segmentation,'hdr'))
                app.segmentation.untouch = 1;
                app.segmentation.hdr = app.data.hdr;
                app.segmentation.img = zeros(size(app.data.img));
            end
            if(app.view_axis == 3)
            elseif(app.view_axis == 2)
                app.drawing.points = app.drawing.points(:,[3 2 1]);
            elseif(app.view_axis == 1)
                app.drawing.points = app.drawing.points(:,[2 3 1]);
            end
            if(size(app.drawing.points,1) > 3)
                for x=2:size(app.drawing.points,1)
                    app.segmentation.img(app.drawing.points(x-1,2),...
                        app.drawing.points(x-1,1),...
                        app.drawing.points(x-1,3)) = 1;
                    app.segmentation.img(app.drawing.points(x,2),...
                        app.drawing.points(x,1),...
                        app.drawing.points(x,3)) = 1;
                    d = app.drawing.points(x,:)-app.drawing.points(x-1,:);
                    d = d/norm(d,2);
                    if(any(isnan(d)))
                        continue
                    end
                    catcher = 1;
                    while (catcher < 500)
                        p = round(app.drawing.points(x-1,:)+d*catcher);
                        app.segmentation.img(p(2),p(1),p(3)) = 1;
                        if(all(p-app.drawing.points(x,:) == 0))
                            break
                        end
                        catcher = catcher + 1;
                    end
                    if(catcher == 500)
                        disp('Catcher!');
                    end
                end
                for iz=app.current_slice:app.current_slice%1:size(app.segmentation.img,3)
                    if(app.view_axis == 3)
                        app.segmentation.img(:,:,iz) = imfill(app.segmentation.img(:,:,iz),'holes');
                    elseif(app.view_axis == 2)
                        app.segmentation.img(:,iz,:) = imfill(app.segmentation.img(:,iz,:),'holes');
                    elseif(app.view_axis == 1)
                        app.segmentation.img(iz,:,:) = imfill(app.segmentation.img(iz,:,:),'holes');
                    end
                end
                [app.labels,app.max_label] = bwlabeln(app.segmentation.img);
            end
            app.DeleteAllDrawingPoints();
            app.drawing.points = [];
        end
        
        function MouseClickedInImage(app,im,hit)
            disp(hit.IntersectionPoint(1:2));
            
            hitx = round(hit.IntersectionPoint(1));
            hity = round(hit.IntersectionPoint(2));
            
            if(app.should_show_selection == true)
                if(app.selection_contour ~= -1)
                    delete(app.selection_contour);
                    app.selection_contour = -1;
                end
                %             app.should_show_selection = false;
                
                hitxrange = intersect(1:size(app.data.img,2),hitx-app.selector_size:hitx+app.selector_size);
                hityrange = intersect(1:size(app.data.img,1),hity-app.selector_size:hity+app.selector_size);
                
                if(app.view_axis == 3)
                    nn = app.labels(hityrange,hitxrange,app.current_slice);
                elseif(app.view_axis == 2)
                    nn = app.labels(hityrange,app.current_slice,hitxrange);
                elseif(app.view_axis == 1)
                    nn = app.labels(app.current_slice,hityrange,hitxrange);
                end
                
                nn = nn(:);
                app.bkseg = app.segmentation;
                for ix=1:length(nn)
                    ij = nn(ix);
                    
                    if(ij ~= 0)
                        disp('Found something');
                        L = app.labels == ij;
                        app.labels(L) = 0;
                        app.segmentation.img(L) = 0;
                    end
                end
                app.UpdateImage();
            elseif(app.drawing.active == true)
                if(~isfield(app.drawing,'points') || ~isfield(app.data,'img') || ...
                        isempty(app.data.img))
                    app.drawing.points = [];
                end
                if(hit.Button == 1)
                    app.drawing.points = [app.drawing.points;hitx hity app.current_slice];
                elseif(hit.Button == 3)
                    app.drawing.active = false;
                    app.drawing.points = [app.drawing.points;app.drawing.points(1,:)];
                    app.ValidateDrawingPoints();
                end
                app.UpdateImage();
            elseif(app.drawing.magic == true)
                if(hit.Button == 1)
                    if(~isprop(app,'labels') || isempty(app.labels) || ...
                            ~isfield(app.segmentation,'hdr'))
                        app.segmentation.untouch = 1;
                        app.segmentation.hdr = app.data.hdr;
                        app.segmentation.img = zeros(size(app.data.img));
                    end
                    app.bkseg = app.segmentation;
                    if(app.view_axis == 3)
                        TheImg = app.data.img(:,:,app.current_slice);
                    elseif(app.view_axis == 2)
                        TheImg = squeeze(app.data.img(:,app.current_slice,:));
                    elseif(app.view_axis == 1)
                        TheImg = squeeze(app.data.img(app.current_slice,:,:));
                    end
                    TheImg = TheImg / max(TheImg(:)) * 255;
                    u = zeros(size(TheImg));
                    u(hity-1:hity+1,hitx-1:hitx+1) = 1;
                    u = activecontour(TheImg,u,100,'Chan-Vese','ContractionBias',0.6);
                    if(app.view_axis == 3)
                        L = app.segmentation.img(:,:,app.current_slice);
                        L(u > 0) = 1;
                        app.segmentation.img(:,:,app.current_slice) = L;
                    elseif(app.view_axis == 2)
                        L = squeeze(app.segmentation.img(:,app.current_slice,:));
                        L(u > 0) = 1;
                        app.segmentation.img(:,app.current_slice,:) = L;
                    elseif(app.view_axis == 1)
                        L = squeeze(app.segmentation.img(app.current_slice,:,:));
                        L(u > 0) = 1;
                        app.segmentation.img(app.current_slice,:,:) = L;
                    end
                    [app.labels,app.max_label] = bwlabeln(app.segmentation.img);
                elseif(hit.Button == 3)
                    app.drawing.magic = false;
                end                
                app.UpdateImage();
            end
            
        end
        
        % For lesion manual editing
        function UpdateSelectionContour(app)
            if(app.selection_contour ~= -1)
                delete(app.selection_contour);
                app.selection_contour = -1;
            end
            if(app.should_show_selection == true)
                hold(app.UIAxes,'on');
                vertices = [5 20 2*app.selector_size 2*app.selector_size];
                app.selection_contour = plot(app.UIAxes,[vertices(1) vertices(1)+vertices(3) vertices(1)+vertices(3) vertices(1)],...
                    [vertices(2) vertices(2) vertices(2)+vertices(4) vertices(2)+vertices(4)],'Color','r');
                hold(app.UIAxes,'off');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0, 'DefaultFigureRenderer', 'opengl');
            rect = get(app.UIAxes,'OuterPosition');
            h = imagesc(app.UIAxes,zeros(rect([4 3])));
            colormap(app.UIAxes,'gray');
            axis(app.UIAxes,'off');
            app.selector_size = 1;
            set(h,'ButtonDownFcn',@app.MouseClickedInImage);
            app.drawing.active = false;
            app.drawing.magic = false;
            opengl('hardware');
        end

        % Button pushed function: LoadFLAIRButton
        function LoadFLAIRButtonPushed(app, event)
            
            [fn,fp] = uigetfile('*.nii*','Select a FLAIR image');
            if(isnumeric(fp) && (fn == 0 || fp == 0))
                return
            end
            
            h = waitbar(0,'Please wait');
            try
                nii = load_untouch_nii(fullfile(fp,fn));
                nii.img = permute(nii.img,[2 1 3]);
                nii.img = flip(nii.img,1);
                nii.img = flip(nii.img,2);
                app.data = nii;
            catch err
                close(h)
                uialert(uifigure,err.message,'');
                return
            end
            
            app.labels = [];
            app.max_label = 0;
            app.DeleteAllDrawingPoints();
            app.segmentation = [];
            app.drawing.points = [];
            
            app.view_axis = 3;
            app.current_slice = round(size(app.data.img,app.view_axis)/2);
            app.MinValue = min(app.data.img(:));
            app.MaxValue = max(app.data.img(:));
            app.cMinValue = app.MinValue;
            app.cMaxValue = app.MaxValue;
            app.UpdateImage();
            close(h)
            
            app.UpdateSliceSlider();
            app.MinvalSlider.MajorTicks = app.MinValue:10:app.MaxValue;
            app.MinvalSlider.Value = double(floor(app.MinValue/app.MaxValue*100));
            app.MaxvalSlider.MajorTicks = app.MinValue:10:app.MaxValue;
            app.MaxvalSlider.Value = double(floor(app.MaxValue/app.MaxValue*100));
            
            app.WorkingonnothingloadedLabel.Text = ['Working on: ' fn];
            
        end

        % Button pushed function: AxialButton
        function AxialButtonPushed(app, event)
            app.view_axis = 3;
            app.UpdateSliceSlider();
            app.UpdateImage();
        end

        % Button pushed function: SagittalButton
        function SagittalButtonPushed(app, event)
            app.view_axis = 2;
            app.UpdateSliceSlider();
            app.UpdateImage();
        end

        % Button pushed function: CoronalButton
        function CoronalButtonPushed(app, event)
            app.view_axis = 1;
            app.UpdateSliceSlider();
            app.UpdateImage();
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.current_slice = round(changingValue/100*size(app.data.img,app.view_axis));
            if(app.current_slice < 1)
                app.current_slice = 1;
            end
            if(app.current_slice > size(app.data.img,app.view_axis))
                app.current_slice = size(app.data.img,app.view_axis);
            end
            app.UpdateImage();
        end

        % Button pushed function: LoadsegmentationButton
        function LoadsegmentationButtonPushed(app, event)
            if(~isfield(app.data,'img'))
                return
            end
            [fn,fp] = uigetfile('*.nii*','Select the corresponding segmentation');
            if(isnumeric(fp) && (fn == 0 || fp == 0))
                return
            end
            h = waitbar(0,'Please wait');
            try
                nii = load_untouch_nii(fullfile(fp,fn));
                nii.img = permute(nii.img,[2 1 3]);
                nii.img = flip(nii.img,1);
                nii.img = flip(nii.img,2);
                app.segmentation = nii;
            catch err
                close(h)
                uialert(uifigure,err.message,'');
                return
            end
            [app.labels,app.max_label] = bwlabeln(app.segmentation.img);
            app.UpdateImage();
            app.bkseg = [];
            app.DeleteAllDrawingPoints();
            app.drawing.points = [];
            close(h)
        end

        % Button pushed function: StartdeletingButton
        function StartdeletingButtonPushed(app, event)
            if(~isfield(app.segmentation,'img'))
                return
            end
            
            app.should_show_selection = true;
            app.UpdateImage();
            app.UpdateSelectionContour();
            
            return
            
            f = figure();
            set(f,'Position',get(app.UIAxes,'Position'));
            app.UpdateImageForAxis(gca);
            try
                [hitx,hity] = ginput(1);
            catch
                return
            end
            close(f)
            hitx = round(hitx);
            hity = round(hity);
            
            hitxrange = intersect(1:size(app.data.img,2),hitx-app.selector_size:hitx+app.selector_size);
            hityrange = intersect(1:size(app.data.img,1),hity-app.selector_size:hity+app.selector_size);
            
            if(app.view_axis == 3)
                nn = app.labels(hityrange,hitxrange,app.current_slice);
            elseif(app.view_axis == 2)
                nn = app.labels(hityrange,app.current_slice,hitxrange);
            elseif(app.view_axis == 1)
                nn = app.labels(app.current_slice,hityrange,hitxrange);
            end
            
            nn = nn(:);
            app.bkseg = app.segmentation;
            for ix=1:length(nn)
                ij = nn(ix);
                
                if(ij ~= 0)
                    L = app.labels == ij;
                    app.labels(L) = 0;
                    app.segmentation.img(L) = 0;
                    app.UpdateImage();
                end
            end
        end

        % Button pushed function: SaveeditedsegmButton
        function SaveeditedsegmButtonPushed(app, event)
            if(~isfield(app.segmentation,'img'))
                return
            end
            [fn,fp] = uiputfile('*.nii','Save segmentation as');
            if(isnumeric(fp) && (fp == 0 || fn == 0))
                return
            end
            nii = app.segmentation;
            nii.img = permute(nii.img,[2 1 3]);
            nii.img = flip(nii.img,1);
            nii.img = flip(nii.img,2);
            h = waitbar(0,'Wait');
            try
                save_untouch_nii(nii,fullfile(fp,fn));
            catch err
                close(h)
                uialert(uifigure,err.message,'');
            end
            close(h)
        end

        % Value changing function: MaxvalSlider
        function MaxvalSliderValueChanging(app, event)
            changingValue = event.Value;
            app.cMaxValue = round(changingValue/100*app.MaxValue);
            app.UpdateImage();
        end

        % Value changing function: MinvalSlider
        function MinvalSliderValueChanging(app, event)
            changingValue = event.Value;
            app.cMinValue = round(changingValue/100*app.MaxValue);
            app.UpdateImage();
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            app.drawing.magic = false;
            app.drawing.active = false;
            if(isfield(app.bkseg,'img'))
                app.segmentation = app.bkseg;
                [app.labels,app.max_label] = bwlabeln(app.segmentation.img);
                app.UpdateImage();
            end
        end

        % Button pushed function: SelectionsensitivityButton
        function SelectionsensitivityButtonPushed(app, event)
            answer = inputdlg(['Please input the new sensitivity in pixel, (current ' num2str(app.selector_size) ')']);
            if(isempty(answer))
                return
            end
            answer = answer{1};
            if(ischar(answer))
                answer = str2double(answer);
            end
            if(answer > 0 && answer <= 10)
                app.selector_size = answer;
            else
                uialert(uifigure,'Invalid values! 0 < x <= 10 pixels','');
            end
        end

        % Button pushed function: StopdeletingButton
        function StopdeletingButtonPushed(app, event)
            app.drawing.magic = false;
            app.should_show_selection = false;
            app.UpdateImage();
            app.UpdateSelectionContour();
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.drawing.magic = false;
            if(~isfield(app.drawing,'active') || app.drawing.active == false)
                app.drawing.active = true;
                app.should_show_selection = false;
                app.UpdateSelectionContour();
            elseif(app.drawing.active == true)
                app.drawing.active = false;
            end
        end

        % Button pushed function: DeleteallButton
        function DeleteallButtonPushed(app, event)
            if(app.view_axis == 3)
                nn = app.labels(:,:,app.current_slice);
            elseif(app.view_axis == 2)
                nn = app.labels(:,app.current_slice,:);
            elseif(app.view_axis == 1)
                nn = app.labels(app.current_slice,:,:);
            end
            nn = unique(nn(nn(:)~=0));
            app.bkseg = app.segmentation;
            for ix=1:length(nn)
                ij = nn(ix);
                if(ij ~= 0)
                    L = app.labels == ij;
                    app.labels(L) = 0;
                    app.segmentation.img(L) = 0;
                    app.UpdateImage();
                end
            end
        end

        % Button pushed function: MagicdrawButton
        function MagicdrawButtonPushed(app, event)
            app.drawing.active = false;
            app.drawing.magic = ~app.drawing.magic;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if(~isfield(app.data,'img') || isempty(app.data.img))
                return
            end
            if(strcmp(key,'uparrow'))
                app.current_slice = app.current_slice + 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();
            elseif(strcmp(key,'downarrow'))
                app.current_slice = app.current_slice - 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if(~isfield(app.data,'img') || isempty(app.data.img))
                return
            end
            if(verticalScrollAmount > 0 && verticalScrollCount < 0)
                app.current_slice = app.current_slice + 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();                
            elseif(verticalScrollAmount > 0 && verticalScrollCount > 0)
                app.current_slice = app.current_slice - 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'alberto@isi.uu.nl';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadFLAIRButton = uibutton(app.UIFigure, 'push');
            app.LoadFLAIRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFLAIRButtonPushed, true);
            app.LoadFLAIRButton.Text = 'Load FLAIR';
            app.LoadsegmentationButton = uibutton(app.UIFigure, 'push');
            app.LoadsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsegmentationButtonPushed, true);
            app.LoadsegmentationButton.Text = {'Load segmentation'; ''};
            app.StartdeletingButton = uibutton(app.UIFigure, 'push');
            app.StartdeletingButton.ButtonPushedFcn = createCallbackFcn(app, @StartdeletingButtonPushed, true);
            app.StartdeletingButton.Text = 'Start deleting';
            app.SaveeditedsegmButton = uibutton(app.UIFigure, 'push');
            app.SaveeditedsegmButton.ButtonPushedFcn = createCallbackFcn(app, @SaveeditedsegmButtonPushed, true);
            app.SaveeditedsegmButton.Text = 'Save edited segm.';
            app.CoronalButton = uibutton(app.UIFigure, 'push');
            app.CoronalButton.ButtonPushedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalButton.Text = 'Coronal';
            app.SagittalButton = uibutton(app.UIFigure, 'push');
            app.SagittalButton.ButtonPushedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalButton.Text = 'Sagittal';
            app.AxialButton = uibutton(app.UIFigure, 'push');
            app.AxialButton.ButtonPushedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialButton.Text = 'Axial';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.MinvalSliderLabel = uilabel(app.UIFigure);
            app.MinvalSliderLabel.Text = 'Min val';
            app.MinvalSlider = uislider(app.UIFigure);
            app.MinvalSlider.ValueChangingFcn = createCallbackFcn(app, @MinvalSliderValueChanging, true);
            app.MaxvalSliderLabel = uilabel(app.UIFigure);
            app.MaxvalSliderLabel.Text = 'Max val';
            app.MaxvalSlider = uislider(app.UIFigure);
            app.MaxvalSlider.ValueChangingFcn = createCallbackFcn(app, @MaxvalSliderValueChanging, true);
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ViewcontrolLabel = uilabel(app.UIFigure);
            app.ViewcontrolLabel.Text = 'View control';
            app.DatamgmtLabel = uilabel(app.UIFigure);
            app.DatamgmtLabel.Text = 'Data mgmt.';
            app.Labelediting = uilabel(app.UIFigure);
            app.Labelediting.Text = 'Label editing';
            app.SelectionsensitivityButton = uibutton(app.UIFigure, 'push');
            app.SelectionsensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionsensitivityButtonPushed, true);
            app.SelectionsensitivityButton.Text = 'Selection sensitivity';
            app.BrightnessandslicemgmtLabel = uilabel(app.UIFigure);
            app.BrightnessandslicemgmtLabel.Text = 'Brightness and slice mgmt.';
            app.StopdeletingButton = uibutton(app.UIFigure, 'push');
            app.StopdeletingButton.ButtonPushedFcn = createCallbackFcn(app, @StopdeletingButtonPushed, true);
            app.StopdeletingButton.Text = 'Stop deleting';
            app.LesionEditorBetaLabel = uilabel(app.UIFigure);
            app.LesionEditorBetaLabel.Text = 'Lesion Editor Beta';
            app.WorkingonnothingloadedLabel = uilabel(app.UIFigure);
            app.WorkingonnothingloadedLabel.Text = 'Working on: nothing loaded';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.DeleteallButton = uibutton(app.UIFigure, 'push');
            app.DeleteallButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteallButtonPushed, true);
            app.DeleteallButton.Text = 'Delete all';
            app.MagicdrawButton = uibutton(app.UIFigure, 'push');
            app.MagicdrawButton.ButtonPushedFcn = createCallbackFcn(app, @MagicdrawButtonPushed, true);
            app.MagicdrawButton.Text = 'Magic draw';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LesionEditor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end